classdef scgeatoolApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        ImportDataMenu              matlab.ui.container.Menu
        ExportSaveDataMenu          matlab.ui.container.Menu
        CloseMenu                   matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        Menu_8                      matlab.ui.container.Menu
        Menu2_8                     matlab.ui.container.Menu
        Menu3_8                     matlab.ui.container.Menu
        Menu4_7                     matlab.ui.container.Menu
        Menu5_5                     matlab.ui.container.Menu
        ViewMenu                    matlab.ui.container.Menu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
        Menu3                       matlab.ui.container.Menu
        Menu4                       matlab.ui.container.Menu
        Menu5                       matlab.ui.container.Menu
        Menu6                       matlab.ui.container.Menu
        Menu7                       matlab.ui.container.Menu
        Menu8                       matlab.ui.container.Menu
        PlotsMenu                   matlab.ui.container.Menu
        Menu_2                      matlab.ui.container.Menu
        Menu2_2                     matlab.ui.container.Menu
        Menu3_2                     matlab.ui.container.Menu
        Menu4_2                     matlab.ui.container.Menu
        Menu5_2                     matlab.ui.container.Menu
        Menu6_2                     matlab.ui.container.Menu
        Menu7_2                     matlab.ui.container.Menu
        AnnotateMenu                matlab.ui.container.Menu
        Menu_3                      matlab.ui.container.Menu
        Menu2_3                     matlab.ui.container.Menu
        Menu3_3                     matlab.ui.container.Menu
        Menu4_3                     matlab.ui.container.Menu
        Menu5_3                     matlab.ui.container.Menu
        Menu6_3                     matlab.ui.container.Menu
        Menu7_3                     matlab.ui.container.Menu
        AnalyzeMenu                 matlab.ui.container.Menu
        Menu_4                      matlab.ui.container.Menu
        Menu2_4                     matlab.ui.container.Menu
        Menu3_4                     matlab.ui.container.Menu
        Menu4_4                     matlab.ui.container.Menu
        Menu5_4                     matlab.ui.container.Menu
        Menu6_4                     matlab.ui.container.Menu
        Menu7_4                     matlab.ui.container.Menu
        NetworkMenu                 matlab.ui.container.Menu
        Menu_5                      matlab.ui.container.Menu
        Menu2_5                     matlab.ui.container.Menu
        Menu3_5                     matlab.ui.container.Menu
        Menu4_5                     matlab.ui.container.Menu
        ExternalMenu                matlab.ui.container.Menu
        SetWorkingFolderMenu        matlab.ui.container.Menu
        Menu2_6                     matlab.ui.container.Menu
        Menu3_6                     matlab.ui.container.Menu
        Menu4_6                     matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        Menu_7                      matlab.ui.container.Menu
        Menu2_7                     matlab.ui.container.Menu
        Menu3_7                     matlab.ui.container.Menu
        Toolbar                     matlab.ui.container.Toolbar
        PushTool_ImportData         matlab.ui.container.toolbar.PushTool
        PushTool_MultigroupingView  matlab.ui.container.toolbar.PushTool
        PushTool_CrossTabulation    matlab.ui.container.toolbar.PushTool
        PushTool4                   matlab.ui.container.toolbar.PushTool
        Toolbar2                    matlab.ui.container.Toolbar
        PushTool_ShowGeneExpr       matlab.ui.container.toolbar.PushTool
        PushTool6                   matlab.ui.container.toolbar.PushTool
        PushTool7                   matlab.ui.container.toolbar.PushTool
        PushTool8                   matlab.ui.container.toolbar.PushTool
        PushTool9                   matlab.ui.container.toolbar.PushTool
        PushTool10                  matlab.ui.container.toolbar.PushTool
        PushTool11                  matlab.ui.container.toolbar.PushTool
        PushTool12                  matlab.ui.container.toolbar.PushTool
        PushTool_SaveX              matlab.ui.container.toolbar.PushTool
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)        
        
    end
    
    properties (Access = public)
        sce = SingleCellExperiment;
    end
    
    methods (Access = private)
        
        function results = in_UpdateMainPlot(app)
            in_UpdatePushToolMenu(app);
            if app.sce.NumCells == 0
                app.UIAxes.Visible = "off";
            else
                results = gui.i_gscatter3(app.sce.s, app.sce.c, 1, [], app.UIAxes);
                title(app.UIAxes, app.sce.title);
                subtitle(app.UIAxes, '[genes x cells]');
                app.UIAxes.Visible = "on";
            end
        end
        
        function results = in_UpdatePushToolMenu(app)
            pushTools = i_GetConditionOnPushTool(app);
            menuItems = i_GetConditionOnMenu(app);
            tag = app.sce.NumCells ~= 0;
            for k = 1:length(pushTools)
                pushTools(k).Enable = tag;
            end
            for k = 1:length(menuItems)
                menuItems(k).Enable = tag;
            end            
        end
        
        function results = i_GetAlwaysOnPushTool(app)
            results = [app.PushTool_ImportData];
        end
        
        function results = i_GetConditionOnPushTool(app)
            pushTools1 = i_GetAlwaysOnPushTool(app);
            pushTools2 = findall(app.UIFigure, 'Type', 'uipushtool');
            results = setdiff(pushTools2, pushTools1);
        end
        
        function results = i_GetAlwaysOnMenu(app)
            results = [app.FileMenu; app.EditMenu; app.ViewMenu;...
                app.AnnotateMenu; app.AnalyzeMenu; app.PlotsMenu;...
                app.NetworkMenu; app.ExternalMenu;...
                app.HelpMenu; app.SetWorkingFolderMenu;...
                app.ImportDataMenu; app.CloseMenu];
        end
        
        function results = i_GetConditionOnMenu(app)
            menuItems1 = i_GetAlwaysOnMenu(app);
            menuItems2 = findall(app.UIFigure, 'Type', 'uimenu');
            length(menuItems2)
            results = setdiff(menuItems2, menuItems1);            
            length(results)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            in_UpdatePushToolMenu(app);
            app.UIAxes.Visible = "off";
            movegui(app.UIFigure, 'center')
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            app.delete;
        end

        % Callback function: PushTool_ImportData
        function PushTool_ImportDataClicked(app, event)
            [res] = gui.sc_openscedlg([], [], app.UIFigure);
            if ~isempty(res)
                app.sce = res;                
            end
            app.in_UpdateMainPlot;
        end

        % Callback function: PushTool_ShowGeneExpr
        function PushTool_ShowGeneExprClicked(app, event)
            xui.callback_ShowGeneExpr(app, event);
        end

        % Callback function: PushTool_SaveX
        function PushTool_SaveXClicked(app, event)
            gui.i_isuifig(app.UIFigure)
            xui.callback_SaveX(app, event);
        end

        % Callback function: PushTool_MultigroupingView
        function PushTool_MultigroupingViewClicked(app, event)
            xui.callback_MultiGroupingView(app, event);
        end

        % Callback function: PushTool_CrossTabulation
        function PushTool_CrossTabulationClicked(app, event)
            xui.callback_CrossTabulation(app, event);
        end

        % Menu selected function: SetWorkingFolderMenu
        function SetWorkingFolderMenuSelected(app, event)
            [done] = gui.i_setwrkdir('externalwrkpath');
            if done
                 gui.myHelpdlg(app.UIFigure, "External program working " + ...
                     "directory is set successfully.");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportDataMenu = uimenu(app.FileMenu);
            app.ImportDataMenu.Text = 'Import Data...';
            app.ExportSaveDataMenu = uimenu(app.FileMenu);
            app.ExportSaveDataMenu.Text = 'Export/Save Data...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Separator = 'on';
            app.CloseMenu.Text = 'Close';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.Menu_8 = uimenu(app.EditMenu);
            app.Menu_8.Text = 'Menu';
            app.Menu2_8 = uimenu(app.EditMenu);
            app.Menu2_8.Separator = 'on';
            app.Menu2_8.Text = 'Menu2';
            app.Menu3_8 = uimenu(app.EditMenu);
            app.Menu3_8.Text = 'Menu3';
            app.Menu4_7 = uimenu(app.EditMenu);
            app.Menu4_7.Text = 'Menu4';
            app.Menu5_5 = uimenu(app.EditMenu);
            app.Menu5_5.Text = 'Menu5';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.Menu = uimenu(app.ViewMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ViewMenu);
            app.Menu2.Text = 'Menu2';
            app.Menu3 = uimenu(app.ViewMenu);
            app.Menu3.Text = 'Menu3';
            app.Menu4 = uimenu(app.ViewMenu);
            app.Menu4.Text = 'Menu4';
            app.Menu5 = uimenu(app.ViewMenu);
            app.Menu5.Text = 'Menu5';
            app.Menu6 = uimenu(app.ViewMenu);
            app.Menu6.Text = 'Menu6';
            app.Menu7 = uimenu(app.ViewMenu);
            app.Menu7.Text = 'Menu7';
            app.Menu8 = uimenu(app.ViewMenu);
            app.Menu8.Text = 'Menu8';
            app.PlotsMenu = uimenu(app.UIFigure);
            app.PlotsMenu.Text = 'Plots';
            app.Menu_2 = uimenu(app.PlotsMenu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.PlotsMenu);
            app.Menu2_2.Text = 'Menu2';
            app.Menu3_2 = uimenu(app.PlotsMenu);
            app.Menu3_2.Text = 'Menu3';
            app.Menu4_2 = uimenu(app.PlotsMenu);
            app.Menu4_2.Text = 'Menu4';
            app.Menu5_2 = uimenu(app.PlotsMenu);
            app.Menu5_2.Text = 'Menu5';
            app.Menu6_2 = uimenu(app.PlotsMenu);
            app.Menu6_2.Text = 'Menu6';
            app.Menu7_2 = uimenu(app.PlotsMenu);
            app.Menu7_2.Text = 'Menu7';
            app.AnnotateMenu = uimenu(app.UIFigure);
            app.AnnotateMenu.Text = 'Annotate';
            app.Menu_3 = uimenu(app.AnnotateMenu);
            app.Menu_3.Text = 'Menu';
            app.Menu2_3 = uimenu(app.AnnotateMenu);
            app.Menu2_3.Text = 'Menu2';
            app.Menu3_3 = uimenu(app.AnnotateMenu);
            app.Menu3_3.Text = 'Menu3';
            app.Menu4_3 = uimenu(app.AnnotateMenu);
            app.Menu4_3.Text = 'Menu4';
            app.Menu5_3 = uimenu(app.AnnotateMenu);
            app.Menu5_3.Text = 'Menu5';
            app.Menu6_3 = uimenu(app.AnnotateMenu);
            app.Menu6_3.Text = 'Menu6';
            app.Menu7_3 = uimenu(app.AnnotateMenu);
            app.Menu7_3.Text = 'Menu7';
            app.AnalyzeMenu = uimenu(app.UIFigure);
            app.AnalyzeMenu.Text = 'Analyze';
            app.Menu_4 = uimenu(app.AnalyzeMenu);
            app.Menu_4.Text = 'Menu';
            app.Menu2_4 = uimenu(app.AnalyzeMenu);
            app.Menu2_4.Text = 'Menu2';
            app.Menu3_4 = uimenu(app.AnalyzeMenu);
            app.Menu3_4.Text = 'Menu3';
            app.Menu4_4 = uimenu(app.AnalyzeMenu);
            app.Menu4_4.Text = 'Menu4';
            app.Menu5_4 = uimenu(app.AnalyzeMenu);
            app.Menu5_4.Text = 'Menu5';
            app.Menu6_4 = uimenu(app.AnalyzeMenu);
            app.Menu6_4.Text = 'Menu6';
            app.Menu7_4 = uimenu(app.AnalyzeMenu);
            app.Menu7_4.Text = 'Menu7';
            app.NetworkMenu = uimenu(app.UIFigure);
            app.NetworkMenu.Text = 'Network';
            app.Menu_5 = uimenu(app.NetworkMenu);
            app.Menu_5.Text = 'Menu';
            app.Menu2_5 = uimenu(app.NetworkMenu);
            app.Menu2_5.Text = 'Menu2';
            app.Menu3_5 = uimenu(app.NetworkMenu);
            app.Menu3_5.Text = 'Menu3';
            app.Menu4_5 = uimenu(app.NetworkMenu);
            app.Menu4_5.Text = 'Menu4';
            app.ExternalMenu = uimenu(app.UIFigure);
            app.ExternalMenu.Text = 'External';
            app.SetWorkingFolderMenu = uimenu(app.ExternalMenu);
            app.SetWorkingFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SetWorkingFolderMenuSelected, true);
            app.SetWorkingFolderMenu.Text = 'Set Working Folder...';
            app.Menu2_6 = uimenu(app.ExternalMenu);
            app.Menu2_6.Text = 'Menu2';
            app.Menu3_6 = uimenu(app.ExternalMenu);
            app.Menu3_6.Text = 'Menu3';
            app.Menu4_6 = uimenu(app.ExternalMenu);
            app.Menu4_6.Text = 'Menu4';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.Menu_7 = uimenu(app.HelpMenu);
            app.Menu_7.Text = 'Menu';
            app.Menu2_7 = uimenu(app.HelpMenu);
            app.Menu2_7.Text = 'Menu2';
            app.Menu3_7 = uimenu(app.HelpMenu);
            app.Menu3_7.Text = 'Menu3';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool_ImportData = uipushtool(app.Toolbar);
            app.PushTool_ImportData.ClickedCallback = createCallbackFcn(app, @PushTool_ImportDataClicked, true);
            app.PushTool_ImportData.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'box3d-center.jpg');
            app.PushTool_MultigroupingView = uipushtool(app.Toolbar);
            app.PushTool_MultigroupingView.Tooltip = {'Multi-grouping View...'};
            app.PushTool_MultigroupingView.ClickedCallback = createCallbackFcn(app, @PushTool_MultigroupingViewClicked, true);
            app.PushTool_MultigroupingView.Enable = 'off';
            app.PushTool_MultigroupingView.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'visibility_18dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_CrossTabulation = uipushtool(app.Toolbar);
            app.PushTool_CrossTabulation.Tooltip = {'Cross tabulation...'};
            app.PushTool_CrossTabulation.ClickedCallback = createCallbackFcn(app, @PushTool_CrossTabulationClicked, true);
            app.PushTool_CrossTabulation.Enable = 'off';
            app.PushTool_CrossTabulation.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'full_stacked_bar_chart_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Separator = 'on';
            app.Toolbar2 = uitoolbar(app.UIFigure);
            app.PushTool_ShowGeneExpr = uipushtool(app.Toolbar2);
            app.PushTool_ShowGeneExpr.ClickedCallback = createCallbackFcn(app, @PushTool_ShowGeneExprClicked, true);
            app.PushTool_ShowGeneExpr.Enable = 'off';
            app.PushTool_ShowGeneExpr.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'google-docs.jpg');
            app.PushTool6 = uipushtool(app.Toolbar2);
            app.PushTool7 = uipushtool(app.Toolbar2);
            app.PushTool8 = uipushtool(app.Toolbar2);
            app.PushTool9 = uipushtool(app.Toolbar2);
            app.PushTool10 = uipushtool(app.Toolbar2);
            app.PushTool11 = uipushtool(app.Toolbar2);
            app.PushTool12 = uipushtool(app.Toolbar2);
            app.PushTool_SaveX = uipushtool(app.Toolbar2);
            app.PushTool_SaveX.ClickedCallback = createCallbackFcn(app, @PushTool_SaveXClicked, true);
            app.PushTool_SaveX.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'floppy-disk.jpg');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scgeatoolApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end